classdef game_of_midi_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GameofMIDIUIFigure          matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        WavetypeDropDownLabel       matlab.ui.control.Label
        WavetypeDropDown            matlab.ui.control.DropDown
        WaitfornoteCheckBox         matlab.ui.control.CheckBox
        StartButton                 matlab.ui.control.Button
        StopResetButton             matlab.ui.control.Button
        SamplerateHzEditFieldLabel  matlab.ui.control.Label
        SamplerateHzEditField       matlab.ui.control.NumericEditField
        PolyphonyLabel              matlab.ui.control.Label
        PolyphonyEditField          matlab.ui.control.NumericEditField
        NotelengthsEditFieldLabel   matlab.ui.control.Label
        NotelengthsEditField        matlab.ui.control.NumericEditField
        IterationsSliderLabel       matlab.ui.control.Label
        IterationsSlider            matlab.ui.control.Slider
        WriteMIDIButton             matlab.ui.control.Button
        ChannelEditFieldLabel       matlab.ui.control.Label
        ChannelEditField            matlab.ui.control.NumericEditField
        IterationsEditField         matlab.ui.control.NumericEditField
        v10Kzra2020Label            matlab.ui.control.Label
        Logo                        matlab.ui.control.Image
        FilenameEditField           matlab.ui.control.EditField
        TempobpmEditFieldLabel      matlab.ui.control.Label
        TempobpmEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        life_matrix logical %logical array, these are the possible values midi can take for note (1-127) and amplitude(1-127)
        kernel logical %kernel to use for 2d convolution operation to calculate orthogonal states
        bw single % colormap for image function
        max_phony uint8 % maximum polyphony (maximum number of voices that can play at once)
        iter_number double % number of iterations of game of life to simulation
        channel uint8 %MIDI Channel
        midi_notes uint8 %array to store midi notes in. Values 1 - 127. Why single? See troubleshooting. 
        midi_vel uint8 %array to store midi velocities in. Why single? See troubleshooting.
        midi_time single %array to store midi time stamps in
        fs single %the sample rate (how many times the waveform is sampled per second) in Hz
        note_len single %the note duration in seconds 
        wave_type char %wave_type to play, can either be square, triangle or sine
        wait_for_note logical %wait till note has played before starting next iteration
        sim logical %a boolean to determine whether or not the simulation is in progress
        file_name char %a character string to determine the midi file name to write
        tempo double % the tempo of the midi file. Why double? See troublshooting. 
        
    end
    
    methods (Access = private)
        %game of midi simulation 
        function of_midi(app)
            %initialise variables
            [m,n] = size(app.life_matrix);
            of_fs = app.fs;% current fs settings;
            app.SamplerateHzEditField.Enable = false; 
            of_phony =  app.max_phony; %current polyphony settings;
            app.PolyphonyEditField.Enable = false;
            of_len = app.note_len; %current note length settings;
            app.NotelengthsEditField.Enable = false;
            of_iter = app.iter_number; %current iteration settings;
            app.IterationsSlider.Enable = false;
            app.IterationsEditField.Enable = false;
            app.WriteMIDIButton.Enable = false;
            
            
            app.midi_notes = zeros(of_phony,of_iter); %array to store midi notes in
            app.midi_vel = zeros(of_phony,of_iter); %array to store midi velocities in
            app.midi_time = zeros(of_phony,of_iter); %array to store midi time stamps in
            
            samples = linspace(0,of_len,of_fs*of_len); %calculate audio sampling time points
            tic 
            for iter = 1:of_iter
            %% Play the Game of Life and update the life matrix. Save the position of live elements in active states    
            ac = 0; %index for storing the number of active elements 
            active_states(:) = {[0 0]}; %reset active states 
            freq(:) = 0; %reset the frequencies
            amp(:) = 0; %reset the amplitudes
            plim = false; %reset the phony limit 
            if app.sim == 0 %check the simulation status, to see whether stop button has been pressed 
                    break %end for loop 
            end
            scores = conv2(app.life_matrix,app.kernel,'same'); %2d convolution operation to calculate orthogonal states
                for i = 1:m
                    for j = 1:n
                        app.life_matrix(i,j) = app.life_rules(app.life_matrix(i,j),scores(i,j));
                        if app.life_matrix(i,j) == 1
                            ac = ac + 1; 
                            if ac > of_phony %ensure no more than max_phony 
                                ac = ac - of_phony; %if more than max_phony, reset ac to 1
                                plim = true; %the phony limit has been reached
                            end
                            active_states(ac) = {[i,j]};
                        end
                    end
                end
              %% Display the updated life matrix. Cycle through active states and compose corresponding MIDI messages 
                image(app.UIAxes,app.life_matrix,'CDataMapping','direct') %replot the image
                drawnow limitrate %limit to 20 fps
                if plim == true %if the phony limit was reached, we need to set the ac index to max_phony
                    ac = of_phony;
                end
                midi_stamp = toc; %time stamp for the current array of midi notes (so they all play the same time)
                if ac > 0   %if there are any live cells 
                        for i = 1:ac
                            note = active_states{i}(2); %note on the x axis
                            velocity = 128 - active_states{i}(1); % vel on the y axis 
                            %as the matrix is 127 * 127 we can directly convert index values to
                            %midi note and amplitude 
                            app.midi_notes(i,iter) = note;
                            app.midi_vel(i,iter) = velocity;
                            app.midi_time(i,iter) = midi_stamp;
                            % convert midi note value to frequency using A(Midi = 69) = 440Hz
                            % convention and equal temperament tuning (/12)
                            freq(i,1) = 440 * 2^((note-69)/12); %use 2^ as octaves are double in frequency each time
                            amp(i,1)  = velocity/127; %amplitude ranges from 0 to 1 
                        end
                %% Sound a single note or chord based on the midi message 
                if strcmp(app.wave_type,'Square') %default behaviour
                    wave = sign(sin(2*pi*freq*samples)) .* amp; %multiply the frequencies by amplitude to ensure they have the correct loudness 
                elseif strcmp(app.wave_type,'Triangle')
                    wave = sawtooth(2*pi*freq*samples) .* amp;
                elseif strcmp(app.wave_type,'Sine')
                    wave = sin(2*pi*freq*samples) .* amp;
                end
                phony = sum(wave); %sum all the waves playing at once to acheive polyphony, normalise between 1 and 0     
                phony = phony/max(phony); % normalise so rate is max [-1 +1] to acheive maximum loudness
                sound(phony,of_fs) % play the wave with sample rate fs
                if app.wait_for_note 
                    wave_stamp = toc; %moving wave_stamp to the start of the play loop is one way of accounting for proc time (by using the current proc time for next iteration). But seems unstable. 
                    while toc - wave_stamp < of_len + .2 %allow a fifth of a second to play the note
                    end
                end
                end
            end
            app.sim = 0; %the simulation has ended
            app.SamplerateHzEditField.Enable = true;
            app.PolyphonyEditField.Enable = true;
            app.NotelengthsEditField.Enable = true;
            app.IterationsEditField.Enable = true;
            app.IterationsSlider.Enable = true;
            app.WriteMIDIButton.Enable = true;
            end
        
        %axes button down call back 
        function lineCallback(~,src,evt)
            index = round(evt.IntersectionPoint(1:2)); %round the coordinates of the click to nearest whole number
               if src.CData(index(2),index(1)) == 0
                src.CData(index(2),index(1)) = 1; 
               else
                src.CData(index(2),index(1)) = 0;    
               end
        end
        
        %game of life rules
        function state = life_rules(~,life,score)
            if score == 3 %a new life is created or sustained
                 state = 1; 
            elseif life == 1 && score == 2 %a life is sustained
                 state = 1;
            else 
                 state = 0; %life dies 
            end
        end
        
        %write MIDI file
        function midi_write(app)

            inotes = nonzeros(app.midi_notes); %flatten the note array and remove zero values
            ivel = nonzeros(app.midi_vel); %flatten the velocity array
            itime = nonzeros(app.midi_time); %flatten the time stamp array
            
            N = numel(inotes); %number of notes
            M = zeros(N,6); %specify 6 columns of the note matrix 
            M(:,1) = 1; %all in track 1 
            M(:,2) = app.channel; %all in midi channel 
            M(:,3) = inotes; %notes
            M(:,4) = ivel; %velocity
            M(:,5) = itime; %start time
            M(:,6) = itime + app.note_len; %end 
            
            [fname,fpath] = uiputfile('*.mid','Save MIDI file',app.file_name); %open up UI box to save file, default title is given as app.file_name
            figure(app.GameofMIDIUIFigure); %necessary in order to bring the app window back to foreground following uiputfile
            if isequal(fname,0) %user selected cancel
                return
            else
            wd = pwd; %save the current working directory
            cd(fpath) %change to fpath directory
            midi_matrix = matrix2midi(M,300,[4 2 24 8],app.tempo); %midi matrix with 300 ticks per 1/4 note, 4/4 time and tempo
            writemidi(midi_matrix,fname); %write midi matrix to fname
            cd(wd) %change back to the working directory  
            end
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        %intialise global variables
        app.iter_number = app.IterationsSlider.Value;
        logo = load("gameofmidilogo.mat");
        app.life_matrix = logo.life_matrix;
        app.bw = [0 0 0; 1 1 1]; 
        app.fs = app.SamplerateHzEditField.Value;
        app.note_len = app.NotelengthsEditField.Value;
        app.max_phony = app.PolyphonyEditField.Value;
        app.wave_type = app.WavetypeDropDown.Value;
        app.channel = app.ChannelEditField.Value;
        app.sim = 0; 
        app.kernel = [1,1,1;1,0,1;1,1,1]; 
        app.file_name = app.FilenameEditField.Value;
        app.tempo = app.TempobpmEditField.Value;
        
        %update the iterations numeric field
        app.IterationsEditField.Value = app.iter_number;
        
        %disable the write MIDI button
        app.WriteMIDIButton.Enable = false;
        
        %create axes
        image(app.UIAxes,app.life_matrix,'CDataMapping','direct','ButtonDownFcn',@app.lineCallback); %initialise image with button click call back
        app.UIAxes.YTickLabel = [127,1]; %reverse the y axis tick labels (so velocity increases with y)
        colormap(app.UIAxes,app.bw) %black and white colormap
        app.UIAxes.Toolbar.Visible = 'off'; %disable the toolbar
        
        end

        % Value changed function: SamplerateHzEditField
        function SamplerateHzEditFieldValueChanged(app, event)
            app.fs = app.SamplerateHzEditField.Value;
        end

        % Value changed function: WaitfornoteCheckBox
        function WaitfornoteCheckBoxValueChanged(app, event)
            app.wait_for_note= app.WaitfornoteCheckBox.Value;     
        end

        % Value changed function: PolyphonyEditField
        function PolyphonyEditFieldValueChanged(app, event)
            app.max_phony = app.PolyphonyEditField.Value;
        end

        % Value changed function: NotelengthsEditField
        function NotelengthsEditFieldValueChanged(app, event)
            app.note_len = app.NotelengthsEditField.Value;       
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            app.channel = app.ChannelEditField.Value;
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            app.iter_number = round(app.IterationsSlider.Value);
        end

        % Value changing function: IterationsSlider
        function IterationsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.IterationsEditField.Value = round(changingValue); %the edit field must be updated by a changing value
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.sim = 1; %simulation is in progress
            app.life_matrix = app.UIAxes.Children.CData; %retrieve the current life_matrix seed
            of_midi(app); %begin simulation
        end

        % Button pushed function: StopResetButton
        function StopResetButtonPushed(app, event)
            if app.sim == 1
                app.sim = 0; %update sim status and end iter for loop
                app.WriteMIDIButton.Enable = true;% allow the user to press the write midi button
                image(app.UIAxes,app.life_matrix,'CDataMapping','direct','ButtonDownFcn',@app.lineCallback); %allow the user to edit matrix
            else
                app.WriteMIDIButton.Enable = false;% user can no longer write a midi file
                app.life_matrix = false(127,127); %reset the life matrix
                image(app.UIAxes,app.life_matrix,'CDataMapping','direct','ButtonDownFcn',@app.lineCallback); %reset the axes
            end
        end

        % Value changed function: WavetypeDropDown
        function WavetypeDropDownValueChanged(app, event)
            app.wave_type = app.WavetypeDropDown.Value;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.file_name = app.FilenameEditField.Value; 
        end

        % Value changed function: TempobpmEditField
        function TempobpmEditFieldValueChanged(app, event)
            app.tempo = app.TempobpmEditField.Value;
        end

        % Button pushed function: WriteMIDIButton
        function WriteMIDIButtonPushed(app, event)
            midi_write(app);
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            app.iter_number = app.IterationsEditField.Value;
            app.IterationsSlider.Value = app.iter_number;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GameofMIDIUIFigure = uifigure('Visible', 'off');
            app.GameofMIDIUIFigure.Name = 'Game of MIDI';
            app.UIAxes = uiaxes(app.GameofMIDIUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Pitch')
            ylabel(app.UIAxes, 'Velocity')
            app.UIAxes.XLim = [1 127];
            app.UIAxes.YLim = [1 127];
            app.UIAxes.XTick = [1 127];
            app.UIAxes.XTickLabel = {''; '127'};
            app.UIAxes.YTick = [1 127];
            app.UIAxes.YTickLabel = {'1'; '127'};
            app.WavetypeDropDownLabel = uilabel(app.GameofMIDIUIFigure);
            app.WavetypeDropDownLabel.Text = {'Wave type'; ''};
            app.WavetypeDropDown = uidropdown(app.GameofMIDIUIFigure);
            app.WavetypeDropDown.Items = {'Triangle', 'Square', 'Sine'};
            app.WavetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WavetypeDropDownValueChanged, true);
            app.WavetypeDropDown.Value = 'Square';
            app.WaitfornoteCheckBox = uicheckbox(app.GameofMIDIUIFigure);
            app.WaitfornoteCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaitfornoteCheckBoxValueChanged, true);
            app.WaitfornoteCheckBox.Text = {'Wait for note'; ''};
            app.StartButton = uibutton(app.GameofMIDIUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopResetButton = uibutton(app.GameofMIDIUIFigure, 'push');
            app.StopResetButton.ButtonPushedFcn = createCallbackFcn(app, @StopResetButtonPushed, true);
            app.StopResetButton.Text = 'Stop / Reset';
            app.SamplerateHzEditFieldLabel = uilabel(app.GameofMIDIUIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [1000 48000];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            app.SamplerateHzEditField.Value = 8192;
            app.PolyphonyLabel = uilabel(app.GameofMIDIUIFigure);
            app.PolyphonyLabel.Text = 'Polyphony';
            app.PolyphonyEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.PolyphonyEditField.Limits = [1 1000];
            app.PolyphonyEditField.RoundFractionalValues = 'on';
            app.PolyphonyEditField.ValueChangedFcn = createCallbackFcn(app, @PolyphonyEditFieldValueChanged, true);
            app.PolyphonyEditField.Value = 100;
            app.NotelengthsEditFieldLabel = uilabel(app.GameofMIDIUIFigure);
            app.NotelengthsEditFieldLabel.Text = {'Note length (s)'; ''};
            app.NotelengthsEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.NotelengthsEditField.Limits = [0.1 5];
            app.NotelengthsEditField.ValueChangedFcn = createCallbackFcn(app, @NotelengthsEditFieldValueChanged, true);
            app.NotelengthsEditField.Value = 0.5;
            app.IterationsSliderLabel = uilabel(app.GameofMIDIUIFigure);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.GameofMIDIUIFigure);
            app.IterationsSlider.Limits = [1 2500];
            app.IterationsSlider.MajorTicks = [1 1000 2500];
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.ValueChangingFcn = createCallbackFcn(app, @IterationsSliderValueChanging, true);
            app.IterationsSlider.MinorTicks = [];
            app.IterationsSlider.Tooltip = {''};
            app.IterationsSlider.Value = 500;
            app.WriteMIDIButton = uibutton(app.GameofMIDIUIFigure, 'push');
            app.WriteMIDIButton.ButtonPushedFcn = createCallbackFcn(app, @WriteMIDIButtonPushed, true);
            app.WriteMIDIButton.Text = 'Write MIDI';
            app.ChannelEditFieldLabel = uilabel(app.GameofMIDIUIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.ChannelEditField.Limits = [1 16];
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.ChannelEditField.Value = 1;
            app.IterationsEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 2500];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 1;
            app.v10Kzra2020Label = uilabel(app.GameofMIDIUIFigure);
            app.v10Kzra2020Label.Text = 'v1.0 © Kzra, 2020';
            app.Logo = uiimage(app.GameofMIDIUIFigure);
            app.Logo.ImageSource = 'Game_of_midi.png';
            app.FilenameEditField = uieditfield(app.GameofMIDIUIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'game_of_midi.mid';
            app.TempobpmEditFieldLabel = uilabel(app.GameofMIDIUIFigure);
            app.TempobpmEditFieldLabel.Text = 'Tempo (bpm)';
            app.TempobpmEditField = uieditfield(app.GameofMIDIUIFigure, 'numeric');
            app.TempobpmEditField.Limits = [6 500];
            app.TempobpmEditField.RoundFractionalValues = 'on';
            app.TempobpmEditField.ValueChangedFcn = createCallbackFcn(app, @TempobpmEditFieldValueChanged, true);
            app.TempobpmEditField.Value = 120;
            % Show the figure after all components are created
            app.GameofMIDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_of_midi_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GameofMIDIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GameofMIDIUIFigure)
        end
    end
end